classdef sift_mask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImportSIFTdataButton           matlab.ui.control.Button
        Thresholdpval01EditField       matlab.ui.control.NumericEditField
        Thresholdpval01EditFieldLabel  matlab.ui.control.Label
        ApplythemaskandquitButton      matlab.ui.control.Button
        SelectedFreqHzEditField        matlab.ui.control.NumericEditField
        SelectedFreqHzEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectedFreqHzEditField
        function selectedFreqHz(app, event)
            value = app.SelectedFreqHzEditField.Value;
            ylimValues = get(app.UIAxes, 'YLim');

            % Delete these line objects
            existingLines = findobj(app.UIAxes, 'Tag', 'freqSelection');
            delete(existingLines);

            % Plot a new vertical line
            line(app.UIAxes, [value value], ylimValues, 'color', [1 0 0 0.5], 'Tag', 'freqSelection')

        end

        % Value changed function: Thresholdpval01EditField
        function thresholdPval(app, event)
            pValue         = app.Thresholdpval01EditField.Value;
            selectedFreqHz = app.SelectedFreqHzEditField.Value;

            % Import EEG from the base workspace.
            EEG            = evalin("base", 'EEG');
            dDTF08_mean    = evalin("base", 'dDTF08_mean');
            freqBins       = evalin("base", 'freqBins');
            freqConnMatrix = evalin("base", 'freqConnMatrix');
            
            % Z-score transformation to obtain p-values.
            peakFreqIdx = find(freqBins == selectedFreqHz); % Determined by visual inspection of the overlaid plots.
            peakValues  = freqConnMatrix(:,peakFreqIdx);
            peakValuesZ = zscore(peakValues);

            % Determine the critical value.
            threshold_p       = pValue;
            threshold_z       = norminv(1-threshold_p, 0, 1);
            sigificantEdgeIdx = find(peakValuesZ>threshold_z);
            threshold_rawVal  = min(peakValues(sigificantEdgeIdx));

            % Delete these line objects
            existingPoints = findobj(app.UIAxes, 'Tag', 'significancePoints');
            delete(existingPoints);

            % Plot significant data points.
            hold(app.UIAxes);
            plot(app.UIAxes, freqBins(peakFreqIdx), freqConnMatrix(sigificantEdgeIdx, peakFreqIdx)',...
                '.', 'markersize', 14, 'color', [1 0 0 0.25], 'Tag', 'significancePoints')
            hold(app.UIAxes);

            % Output the variables in the base workspace.
            assignin("base", 'peakFreqIdx', peakFreqIdx)
            assignin("base", 'threshold_rawVal', threshold_rawVal)

        end

        % Button pushed function: ImportSIFTdataButton
        function importSiftData(app, event)
            
            % Clear axes.
            cla(app.UIAxes)

            % Clear the edit boxes.
            app.Thresholdpval01EditField.Value = 0;
            app.SelectedFreqHzEditField.Value  = 0;

            % Import EEG from the base workspace.
            EEG = evalin("base", 'EEG');

            % Extract dDTF08.
            dDTF08 = EEG.CAT.Conn.dDTF08;
            timeLength = length(EEG.CAT.Conn.winCenterTimes);
            freqBins = EEG.CAT.Conn.freqs;

            % Take average across time.
            dDTF08_mean = mean(dDTF08, 4);

            % Extract freq-domain graph edges. 
            counter               = 0;
            selfConnectionCounter = 0;
            freqConnMatrix = zeros(size(dDTF08_mean,1)^2, size(dDTF08_mean,3));
            zeroIdx = zeros(size(dDTF08_mean,1),1);
            for toIdx = 1:size(dDTF08_mean,1)
                for fromIdx = 1:size(dDTF08_mean,2)
                    counter = counter+1;
                    if toIdx == fromIdx
                        selfConnectionCounter = selfConnectionCounter+1;
                        zeroIdx(selfConnectionCounter) = counter;
                        continue
                    end
                    currentEdgeConn = dDTF08_mean(toIdx, fromIdx, :);
                    freqConnMatrix(counter,:) = currentEdgeConn;
                end
            end
            freqConnMatrix(zeroIdx,:) = [];

            % Display the overlaid line plots.
            plot(app.UIAxes, freqBins, freqConnMatrix', 'color', [0 0 0.1 0.15], 'Tag', 'linePlots')
            xlim(app.UIAxes, [1 30])

            % Output the variables in the base workspace.
            assignin("base", 'dDTF08_mean',    dDTF08_mean)
            assignin("base", 'freqBins',       freqBins)
            assignin("base", 'freqConnMatrix', freqConnMatrix)

        end

        % Button pushed function: ApplythemaskandquitButton
        function applyTheMaskAndQuit(app, event)

            % Import EEG from the base workspace.
            EEG              = evalin("base", 'EEG');
            dDTF08_mean      = evalin("base", 'dDTF08_mean');
            threshold_rawVal = evalin("base", 'threshold_rawVal');
            peakFreqIdx      = evalin("base", 'peakFreqIdx');

            % Apply the significance mask.
            dDTF08_masked = dDTF08_mean;
            counter = 0;
            selfConnectionCounter = 0;
            for toIdx = 1:size(dDTF08_mean,1)
                for fromIdx = 1:size(dDTF08_mean,2)

                    counter = counter+1;

                    % Mask if self-connection.
                    if toIdx == fromIdx
                        selfConnectionCounter = selfConnectionCounter+1;
                        dDTF08_masked(toIdx, fromIdx, :) = 0;
                        continue
                    end

                    % Mask is smaller than the threshold.
                    selectedValue = dDTF08_masked(toIdx, fromIdx, peakFreqIdx);
                    if selectedValue<threshold_rawVal
                        dDTF08_masked(toIdx, fromIdx, :) = 0;
                    end
                end
            end

            % Apply the mask to the data.
            timeLength = length(EEG.CAT.Conn.winCenterTimes);
            dDTF08_masked_originalDimensions = repmat(dDTF08_masked, [1 1 1 timeLength]);
            EEG.CAT.Conn.dDTF08 = dDTF08_masked_originalDimensions;
            assignin("base", 'EEG', EEG);

            % Close the figure.
            disp('The significance mask is applied to dDTF08 successfully.')
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'All graph edges (dDTF)')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Connectivity (a.u.)')
            zlabel(app.UIAxes, 'Z')
            app.SelectedFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedFreqHzEditFieldLabel.Text = 'Selected Freq (Hz)';
            app.SelectedFreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectedFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @selectedFreqHz, true);
            app.ApplythemaskandquitButton = uibutton(app.UIFigure, 'push');
            app.ApplythemaskandquitButton.ButtonPushedFcn = createCallbackFcn(app, @applyTheMaskAndQuit, true);
            app.ApplythemaskandquitButton.Text = 'Apply the mask and quit';
            app.Thresholdpval01EditFieldLabel = uilabel(app.UIFigure);
            app.Thresholdpval01EditFieldLabel.Text = 'Threshold p-val [0-1]';
            app.Thresholdpval01EditField = uieditfield(app.UIFigure, 'numeric');
            app.Thresholdpval01EditField.ValueChangedFcn = createCallbackFcn(app, @thresholdPval, true);
            app.ImportSIFTdataButton = uibutton(app.UIFigure, 'push');
            app.ImportSIFTdataButton.ButtonPushedFcn = createCallbackFcn(app, @importSiftData, true);
            app.ImportSIFTdataButton.Text = 'Import SIFT data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sift_mask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end